classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = public)
    end

 
    methods (Access = public)
        function resultPanel = CreateSongResult(app, g, songTitle, artist, coverImgSrc, num)            
            % create panels with the corresponding data 
            % 640,480 => size
            
            %audioSrc = 'https://p.scdn.co/mp3-preview/1e7df67cef52cd4578f2a344f4bc875cac394301?cid=28ca2d3814414c08858c196d669a3838'
            
            resultPanel = uipanel(g);
            section = uigridlayout(resultPanel, [1 3]);
            section.ColumnWidth = {'fit', 'fit', 'fit', 'fit'};
            
            % COVER IMAGE
            fileUrl = strcat('test',num,'.png');

            urlwrite(coverImgSrc{1}, fileUrl);
            cover = uiimage(section,'ImageSource','test1.png','ScaleMethod','stretch');
            
            % SONG & ARTIST
            songName = uilabel(section, 'Text',songTitle,'WordWrap','on','FontSize',24);
            artistName = uilabel(section, 'Text',artist,'WordWrap','on','FontSize',24);
            
            % AUDIO CLIP
            %songClip = uihtml(section);
            %urlwrite(audioSrc, 'testAudio.wav')
            
            % songClip.HTMLSource = '<audio controls style="padding-left:20px"><source src="/testAudio.wav" type="audio/wav"></audio>'
            
            % uihyperlink
        end
        
        function CreateSongResultsUI(app, data)
            disp(data)
            
            g = uigridlayout(app.UIFigure, [6 1]);
      
            for(i = 1:5)
                songTitle = data.name(i);
                artist = data.artist(i);
                coverImgSrc = data.cover(i);
                disp(coverImgSrc);
    
                resultPanel = CreateSongResult(app, g, songTitle, artist, coverImgSrc, i)  ;
            end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
           CreateSongResultsUI(app, data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end